classdef UWNoiPro506 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnderwaterNoiseProcessingUIFigure  matlab.ui.Figure
        KeepsizepositionCheckBox        matlab.ui.control.CheckBox
        nofilterLabel                   matlab.ui.control.Label
        infendoffileLabel               matlab.ui.control.Label
        v506Label                       matlab.ui.control.Label
        OutputfolderEditField           matlab.ui.control.EditField
        CalibrationfileEditField        matlab.ui.control.EditField
        InputfolderEditField            matlab.ui.control.EditField
        ExitstatusTextArea              matlab.ui.control.TextArea
        ErrorLamp                       matlab.ui.control.Lamp
        DoneLamp                        matlab.ui.control.Lamp
        RunningLamp                     matlab.ui.control.Lamp
        RunButton                       matlab.ui.control.Button
        AddsetupCheckBox                matlab.ui.control.CheckBox
        TofileCheckBox                  matlab.ui.control.CheckBox
        LogDropDown                     matlab.ui.control.DropDown
        PauseafterplotsEditField        matlab.ui.control.NumericEditField
        FigureheightpixEditField        matlab.ui.control.NumericEditField
        FigurewidthpixEditField         matlab.ui.control.NumericEditField
        YmaxdBEditField                 matlab.ui.control.NumericEditField
        YmindBEditField                 matlab.ui.control.NumericEditField
        AutoYCheckBox                   matlab.ui.control.CheckBox
        XmaxHzEditField                 matlab.ui.control.NumericEditField
        XminHzEditField                 matlab.ui.control.NumericEditField
        AutoXCheckBox                   matlab.ui.control.CheckBox
        toSSSpinner                     matlab.ui.control.Spinner
        fromSSSpinner                   matlab.ui.control.Spinner
        KnudsencurvesCheckBox           matlab.ui.control.CheckBox
        AllPSDlinesCheckBox             matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        TitleCheckBox                   matlab.ui.control.CheckBox
        LowpercentileSpinner            matlab.ui.control.Spinner
        HighpercentileSpinner           matlab.ui.control.Spinner
        AveragingDropDown               matlab.ui.control.DropDown
        HighpasscutoffHzEditField       matlab.ui.control.NumericEditField
        LastDDbandDropDown              matlab.ui.control.DropDown
        FirstDDbandDropDown             matlab.ui.control.DropDown
        WindowDropDown                  matlab.ui.control.DropDown
        FFToverlapSpinner               matlab.ui.control.Spinner
        FFTpointsDropDown               matlab.ui.control.DropDown
        SnapshotskipsEditField          matlab.ui.control.NumericEditField
        SnapshotoverlapSpinner          matlab.ui.control.Spinner
        SnapshotlengthsEditField        matlab.ui.control.NumericEditField
        StoptimesEditField              matlab.ui.control.NumericEditField
        StarttimesEditField             matlab.ui.control.NumericEditField
        changeButton_3                  matlab.ui.control.Button
        changeButton_2                  matlab.ui.control.Button
        changeButton                    matlab.ui.control.Button
        SaveTXTfilesCheckBox            matlab.ui.control.CheckBox
        SaveJPEGfilesCheckBox           matlab.ui.control.CheckBox
        BoxplotCheckBox                 matlab.ui.control.CheckBox
        DecidecadePSDCheckBox           matlab.ui.control.CheckBox
        BroadbandPSDCheckBox            matlab.ui.control.CheckBox
        BroadbandSPLCheckBox            matlab.ui.control.CheckBox
        LogDropDownLabel                matlab.ui.control.Label
        WindowDropDownLabel             matlab.ui.control.Label
        AveragingDropDownLabel          matlab.ui.control.Label
        ExitstatusTextAreaLabel         matlab.ui.control.Label
        ErrorLampLabel                  matlab.ui.control.Label
        DoneLabel                       matlab.ui.control.Label
        RunningLampLabel                matlab.ui.control.Label
        OutputfolderEditFieldLabel      matlab.ui.control.Label
        CalibrationfileEditFieldLabel   matlab.ui.control.Label
        InputfolderEditFieldLabel       matlab.ui.control.Label
        PauseafterplotsEditFieldLabel   matlab.ui.control.Label
        toSSSpinnerLabel                matlab.ui.control.Label
        fromSSSpinnerLabel              matlab.ui.control.Label
        FigurewidthpixEditFieldLabel    matlab.ui.control.Label
        FigureheightpixEditFieldLabel   matlab.ui.control.Label
        YmaxdBEditFieldLabel            matlab.ui.control.Label
        XmaxHzEditFieldLabel            matlab.ui.control.Label
        YmindBEditFieldLabel            matlab.ui.control.Label
        XminHzEditFieldLabel            matlab.ui.control.Label
        LastDDbandDropDownLabel         matlab.ui.control.Label
        FirstDDbandDropDownLabel        matlab.ui.control.Label
        LowpercentileSpinnerLabel       matlab.ui.control.Label
        HighpercentileSpinnerLabel      matlab.ui.control.Label
        HighpasscutoffHzEditFieldLabel  matlab.ui.control.Label
        FFToverlapSpinnerLabel          matlab.ui.control.Label
        FFTpointsDropDownLabel          matlab.ui.control.Label
        SnapshotskipsEditFieldLabel     matlab.ui.control.Label
        SnapoverlapLabel                matlab.ui.control.Label
        SnapshotlengthsEditFieldLabel   matlab.ui.control.Label
        StoptimesEditFieldLabel         matlab.ui.control.Label
        StarttimesEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        % set by user dialog boxes and passed to setup
        inputPath 
        calFileName 
        calFilePath
        outputPath
        % function locals
        pathSep
        calfileExt = '*.txt';
        returnVal
        exitStat
        figHandles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ispc  % file path separator: '\' for Windows, '/' for Linux
                app.pathSep= '\';
            else
                app.pathSep= '/';
            end
            app.InputfolderEditField.Value = pwd;
            app.OutputfolderEditField.Value = pwd;
            app.CalibrationfileEditField.Value = pwd;
            app.inputPath = app.InputfolderEditField.Value;
            app.outputPath = app.OutputfolderEditField.Value;
            app.calFilePath = app.CalibrationfileEditField.Value;
            app.RunningLamp.Color = [0 0 0];
            app.DoneLamp.Color = [0 0 0];
            app.ErrorLamp.Color = [0 0 0];
            app.ExitstatusTextArea.Value = '';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Get parameters to be passed to main function
            % from values of controls in gui
            % NOTE: convert checkbox values to boolean using > 0
            Setup.PROCSPL = app.BroadbandSPLCheckBox.Value > 0;
            Setup.PROCPSDB = app.BroadbandPSDCheckBox.Value > 0;
            Setup.PROCPSDD= app.DecidecadePSDCheckBox.Value > 0; 
            Setup.BOXPLT = app.BoxplotCheckBox.Value > 0;
            Setup.STARTTIME = app.StarttimesEditField.Value;
            Setup.STOPTIME = app.StoptimesEditField.Value;
            Setup.SNAPLEN = app.SnapshotlengthsEditField.Value;
            Setup.SNAPOVLAP = app.SnapshotoverlapSpinner.Value;
            Setup.SNAPSKIP = app.SnapshotskipsEditField.Value;
            Setup.NFFT = 2^str2num(app.FFTpointsDropDown.Value);
            Setup.FFTOVLAP = app.FFToverlapSpinner.Value;
            Setup.WTYPE = str2num(app.WindowDropDown.Value);
            Setup.AVGTYPE = str2num(app.AveragingDropDown.Value);
            Setup.HIPASSF = app.HighpasscutoffHzEditField.Value;
            Setup.PRCTILEL = app.LowpercentileSpinner.Value;
            Setup.PRCTILEH = app.HighpercentileSpinner.Value;
            Setup.DDIDXL = str2num(app.FirstDDbandDropDown.Value);
            Setup.DDIDXH = str2num(app.LastDDbandDropDown.Value);
            Setup.DRAWALL = app.AllPSDlinesCheckBox.Value > 0;
            Setup.SAVEJPG = app.SaveJPEGfilesCheckBox.Value > 0;
            Setup.SAVETXT = app.SaveTXTfilesCheckBox.Value > 0;
            Setup.XAXAUTO = app.AutoXCheckBox.Value > 0;
            Setup.YAXAUTO = app.AutoYCheckBox.Value > 0;
            Setup.XAXMIN = app.XminHzEditField.Value;
            Setup.XAXMAX = app.XmaxHzEditField.Value;
            Setup.YAXMIN = app.YmindBEditField.Value;
            Setup.YAXMAX = app.YmaxdBEditField.Value;
            Setup.LEGND = app.LegendCheckBox.Value > 0;
            Setup.TITL = app.TitleCheckBox.Value > 0;
            Setup.FIGSIZE = [app.FigurewidthpixEditField.Value ...
                app.FigureheightpixEditField.Value];
            Setup.NORESIZ = app.KeepsizepositionCheckBox.Value > 0;
            Setup.VERB = str2num(app.LogDropDown.Value);
            Setup.PLTPAUSE = app.PauseafterplotsEditField.Value;
            Setup.KNDSDRAW = app.KnudsencurvesCheckBox.Value > 0;
            Setup.KNDSSIDX =  app.fromSSSpinner.Value : ...
                app.toSSSpinner.Value ;
            % next three are set by button callbacks
            Setup.INPATH = app.inputPath; 
            Setup.CALFPATH = [app.calFilePath,app.calFileName]; 
            Setup.OUTPATH = app.outputPath;
            Setup.LOGFILE = app.TofileCheckBox.Value > 0;
            Setup.LOGSETUP = app.AddsetupCheckBox.Value > 0;
            
            % Setup % display fields in command

            % assignin('base','Setup',Setup); % copy to workspace

            app.ExitstatusTextArea.Value = '';
            app.DoneLamp.Color = [0 0 0];
            app.ErrorLamp.Color = [0 0 0];
            app.RunningLamp.Color = [0 1 0]; % green
            %app.RunButton.Enable = "off";

            %%%%%%%%%%%%%%%%%%%% exec main function %%%%%%%%%%%%%%%%%%%%%
            [app.returnVal,app.exitStat,app.figHandles] = ...
                UWNoiPro506main(Setup); 
            
            app.ExitstatusTextArea.Value = app.exitStat;
            app.RunningLamp.Color = [0 0 0]; 
            %app.RunButton.Enable = "on";
            if app.returnVal == 0 % no error
                app.DoneLamp.Color = [0 1 0]; % green
            else
                app.ErrorLamp.Color = [1 0 0]; % red
            end
        end

        % Button pushed function: changeButton
        function changeButtonPushed(app, event)
            value = app.inputPath; % current value
            app.inputPath = uigetdir(app.InputfolderEditField.Value, ...
                'Select INPUT folder');
            if app.inputPath == 0 % user canceled
                app.inputPath = value;
            else
                app.InputfolderEditField.Value = app.inputPath;
            end
        end

        % Button pushed function: changeButton_2
        function changeButton_2Pushed(app, event)
            value = app.calFileName; % current file
            [app.calFileName,app.calFilePath] = uigetfile( ...
                [app.calFilePath app.pathSep app.calfileExt],...
                'Select calibration file');
            if app.calFileName == 0 % user canceled
                app.calFileName = value;
            else
                app.CalibrationfileEditField.Value = ...
                    [app.calFilePath app.calFileName];
            end
        end

        % Button pushed function: changeButton_3
        function changeButton_3Pushed(app, event)
            value = app.outputPath; % current value
            app.outputPath = uigetdir(app.OutputfolderEditField.Value, ...
                'Select OUTPUT folder');
            if app.outputPath == 0 % user canceled
                app.outputPath = value;
            else
                app.OutputfolderEditField.Value = app.outputPath;
            end
        end

        % Value changed function: TofileCheckBox
        function TofileCheckBoxValueChanged(app, event)
            value = app.TofileCheckBox.Value;
            if value == 0
                app.AddsetupCheckBox.Enable = "off";
            else
                app.AddsetupCheckBox.Enable = "on";
            end
        end

        % Value changed function: AutoXCheckBox
        function AutoXCheckBoxValueChanged(app, event)
            value = app.AutoXCheckBox.Value;
            if value > 0
                app.XmaxHzEditField.Enable = "off";
                app.XminHzEditField.Enable = "off";
            else
                app.XmaxHzEditField.Enable = "on";
                app.XminHzEditField.Enable = "on";
            end
        end

        % Value changed function: AutoYCheckBox
        function AutoYCheckBoxValueChanged(app, event)
            value = app.AutoYCheckBox.Value;
            if value > 0
                app.YmaxdBEditField.Enable = "off";
                app.YmindBEditField.Enable = "off";
            else
                app.YmaxdBEditField.Enable = "on";
                app.YmindBEditField.Enable = "on";
            end
        end

        % Value changed function: KnudsencurvesCheckBox
        function KnudsencurvesCheckBoxValueChanged(app, event)
            value = app.KnudsencurvesCheckBox.Value;
            if value == 0
                app.fromSSSpinner.Enable = "off";
                app.toSSSpinner.Enable = "off";
            else
                app.fromSSSpinner.Enable = "on";
                app.toSSSpinner.Enable = "on";
            end
        end

        % Value changed function: DecidecadePSDCheckBox
        function DecidecadePSDCheckBoxValueChanged(app, event)
            value = app.DecidecadePSDCheckBox.Value;
            if value == 0
                app.BoxplotCheckBox.Enable = "off";
                app.FirstDDbandDropDown.Enable = "off";
                app.LastDDbandDropDown.Enable = "off";
            else
                app.BoxplotCheckBox.Enable = "on";
                app.FirstDDbandDropDown.Enable = "on";
                app.LastDDbandDropDown.Enable = "on";
            end
        end

        % Value changed function: BroadbandPSDCheckBox
        function BroadbandPSDCheckBoxValueChanged(app, event)
            value = app.BroadbandPSDCheckBox.Value;
            if value == 0
                app.AllPSDlinesCheckBox.Enable = "off";
                app.KnudsencurvesCheckBox.Enable = "off";
            else
                app.AllPSDlinesCheckBox.Enable = "on";
                app.KnudsencurvesCheckBox.Enable = "on";
            end
        end

        % Value changed function: KeepsizepositionCheckBox
        function KeepsizepositionCheckBoxValueChanged(app, event)
            value = app.KeepsizepositionCheckBox.Value;
            if value == 0
                app.FigureheightpixEditField.Enable = "on";
                app.FigurewidthpixEditField.Enable = "on";
            else
                app.FigureheightpixEditField.Enable = "off";
                app.FigurewidthpixEditField.Enable = "off";
            end
        end

        % Close request function: UnderwaterNoiseProcessingUIFigure
        function UnderwaterNoiseProcessingUIFigureCloseRequest(app, event)
            for i = 1:length(app.figHandles)
                delete(app.figHandles(i))
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UnderwaterNoiseProcessingUIFigure = uifigure('Visible', 'off');
            app.UnderwaterNoiseProcessingUIFigure.Name = 'Underwater Noise Processing';
            app.UnderwaterNoiseProcessingUIFigure.CloseRequestFcn = createCallbackFcn(app, @UnderwaterNoiseProcessingUIFigureCloseRequest, true);
            app.StarttimesEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.StarttimesEditFieldLabel.Text = 'Start time / s';
            app.StoptimesEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.StoptimesEditFieldLabel.Text = 'Stop time / s';
            app.SnapshotlengthsEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.SnapshotlengthsEditFieldLabel.Text = 'Snapshot length / s';
            app.SnapoverlapLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.SnapoverlapLabel.Text = 'Snapshot overlap %';
            app.SnapshotskipsEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.SnapshotskipsEditFieldLabel.Text = 'Snapshot skip / s';
            app.FFTpointsDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.FFTpointsDropDownLabel.Text = 'FFT points';
            app.FFToverlapSpinnerLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.FFToverlapSpinnerLabel.Text = 'FFT overlap %';
            app.HighpasscutoffHzEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.HighpasscutoffHzEditFieldLabel.Text = 'High pass cutoff / Hz';
            app.HighpercentileSpinnerLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.HighpercentileSpinnerLabel.Text = 'High percentile';
            app.LowpercentileSpinnerLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.LowpercentileSpinnerLabel.Text = 'Low percentile';
            app.FirstDDbandDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.FirstDDbandDropDownLabel.Enable = 'off';
            app.FirstDDbandDropDownLabel.Text = 'First DD band';
            app.LastDDbandDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.LastDDbandDropDownLabel.Enable = 'off';
            app.LastDDbandDropDownLabel.Text = 'Last DD band';
            app.XminHzEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.XminHzEditFieldLabel.Text = 'X min / Hz';
            app.YmindBEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.YmindBEditFieldLabel.Text = 'Y min / dB';
            app.XmaxHzEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.XmaxHzEditFieldLabel.Text = 'X max / Hz';
            app.YmaxdBEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.YmaxdBEditFieldLabel.Text = 'Y max / dB';
            app.FigureheightpixEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.FigureheightpixEditFieldLabel.Text = 'Figure height / pix';
            app.FigurewidthpixEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.FigurewidthpixEditFieldLabel.Text = 'Figure width / pix';
            app.fromSSSpinnerLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.fromSSSpinnerLabel.Enable = 'off';
            app.fromSSSpinnerLabel.Text = 'from SS';
            app.toSSSpinnerLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.toSSSpinnerLabel.Enable = 'off';
            app.toSSSpinnerLabel.Text = 'to SS';
            app.PauseafterplotsEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.PauseafterplotsEditFieldLabel.Text = {'Pause after'; 'plot / s'};
            app.InputfolderEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.InputfolderEditFieldLabel.Text = 'Input folder';
            app.CalibrationfileEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.CalibrationfileEditFieldLabel.Text = 'Calibration file';
            app.OutputfolderEditFieldLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.OutputfolderEditFieldLabel.Text = 'Output folder';
            app.RunningLampLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.RunningLampLabel.Text = 'Running...';
            app.DoneLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.DoneLabel.Text = 'Done';
            app.ErrorLampLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.ErrorLampLabel.Text = 'Error';
            app.ExitstatusTextAreaLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.ExitstatusTextAreaLabel.Text = 'Exit status';
            app.AveragingDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.AveragingDropDownLabel.Text = 'Averaging';
            app.WindowDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.WindowDropDownLabel.Text = 'Window';
            app.LogDropDownLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.LogDropDownLabel.Text = 'Log';
            app.BroadbandSPLCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.BroadbandSPLCheckBox.Text = 'Broadband SPL';
            app.BroadbandPSDCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.BroadbandPSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @BroadbandPSDCheckBoxValueChanged, true);
            app.BroadbandPSDCheckBox.Text = 'Broadband PSD';
            app.DecidecadePSDCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.DecidecadePSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecidecadePSDCheckBoxValueChanged, true);
            app.DecidecadePSDCheckBox.Text = 'Decidecade PSD';
            app.BoxplotCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.BoxplotCheckBox.Enable = 'off';
            app.BoxplotCheckBox.Text = 'Box plot';
            app.SaveJPEGfilesCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.SaveJPEGfilesCheckBox.Text = 'Save JPEG files';
            app.SaveTXTfilesCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.SaveTXTfilesCheckBox.Text = 'Save TXT files';
            app.changeButton = uibutton(app.UnderwaterNoiseProcessingUIFigure, 'push');
            app.changeButton.ButtonPushedFcn = createCallbackFcn(app, @changeButtonPushed, true);
            app.changeButton.Text = 'change...';
            app.changeButton_2 = uibutton(app.UnderwaterNoiseProcessingUIFigure, 'push');
            app.changeButton_2.ButtonPushedFcn = createCallbackFcn(app, @changeButton_2Pushed, true);
            app.changeButton_2.Text = 'change...';
            app.changeButton_3 = uibutton(app.UnderwaterNoiseProcessingUIFigure, 'push');
            app.changeButton_3.ButtonPushedFcn = createCallbackFcn(app, @changeButton_3Pushed, true);
            app.changeButton_3.Text = 'change...';
            app.StarttimesEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.StarttimesEditField.Limits = [0 Inf];
            app.StoptimesEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.StoptimesEditField.Limits = [0 Inf];
            app.StoptimesEditField.Value = 10;
            app.SnapshotlengthsEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.SnapshotlengthsEditField.Limits = [0.001 Inf];
            app.SnapshotlengthsEditField.Value = 1;
            app.SnapshotoverlapSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.SnapshotoverlapSpinner.Step = 25;
            app.SnapshotoverlapSpinner.Limits = [0 75];
            app.SnapshotskipsEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.SnapshotskipsEditField.Limits = [0 Inf];
            app.FFTpointsDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.FFTpointsDropDown.Items = {'128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536', '131072', '262144', '524288', '1048576'};
            app.FFTpointsDropDown.ItemsData = {'7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.FFTpointsDropDown.Value = '12';
            app.FFToverlapSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.FFToverlapSpinner.Step = 25;
            app.FFToverlapSpinner.Limits = [0 75];
            app.FFToverlapSpinner.Value = 50;
            app.WindowDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.WindowDropDown.Items = {'none (rect)', 'Hamming', 'Blackman', 'Hann'};
            app.WindowDropDown.ItemsData = {'0', '1', '2', '3'};
            app.WindowDropDown.Value = '1';
            app.FirstDDbandDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.FirstDDbandDropDown.Items = {'1 Hz', '1.25 Hz', '1.6 Hz', '2 Hz', '2.5 Hz', '3.15 Hz', '4 Hz', '5 Hz', '6.3 Hz', '8 Hz', '10 Hz', '12.5 Hz', '16 Hz', '20 Hz', '25 Hz', '31.5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '2500 Hz', '3150 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10 kHz', '12.5 kHz', '16 kHz', '20 kHz', '25 kHz', '40 kHz', '50 kHz', '63 kHz', '80 kHz', '100 kHz', '125 kHz', '160 kHz', '200 kHz', '250 kHz', '315 kHz', '400 kHz', '500 kHz', '630 kHz', '800 kHz', '1 MHz'};
            app.FirstDDbandDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61'};
            app.FirstDDbandDropDown.Enable = 'off';
            app.FirstDDbandDropDown.Value = '14';
            app.LastDDbandDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.LastDDbandDropDown.Items = {'1 Hz', '1.25 Hz', '1.6 Hz', '2 Hz', '2.5 Hz', '3.15 Hz', '4 Hz', '5 Hz', '6.3 Hz', '8 Hz', '10 Hz', '12.5 Hz', '16 Hz', '20 Hz', '25 Hz', '31.5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '2500 Hz', '3150 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10 kHz', '12.5 kHz', '16 kHz', '20 kHz', '25 kHz', '40 kHz', '50 kHz', '63 kHz', '80 kHz', '100 kHz', '125 kHz', '160 kHz', '200 kHz', '250 kHz', '315 kHz', '400 kHz', '500 kHz', '630 kHz', '800 kHz', '1 MHz'};
            app.LastDDbandDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61'};
            app.LastDDbandDropDown.Enable = 'off';
            app.LastDDbandDropDown.Value = '41';
            app.HighpasscutoffHzEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.HighpasscutoffHzEditField.Limits = [0 Inf];
            app.AveragingDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.AveragingDropDown.Items = {'Arithmetic mean', 'Median'};
            app.AveragingDropDown.ItemsData = {'1', '2'};
            app.AveragingDropDown.Value = '1';
            app.HighpercentileSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.HighpercentileSpinner.Step = 5;
            app.HighpercentileSpinner.Limits = [50 95];
            app.HighpercentileSpinner.Value = 75;
            app.LowpercentileSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.LowpercentileSpinner.Step = 5;
            app.LowpercentileSpinner.Limits = [5 50];
            app.LowpercentileSpinner.Value = 25;
            app.TitleCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.TitleCheckBox.Text = 'Title';
            app.LegendCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.LegendCheckBox.Text = 'Legend';
            app.AllPSDlinesCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.AllPSDlinesCheckBox.Enable = 'off';
            app.AllPSDlinesCheckBox.Text = 'All PSD lines';
            app.KnudsencurvesCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.KnudsencurvesCheckBox.ValueChangedFcn = createCallbackFcn(app, @KnudsencurvesCheckBoxValueChanged, true);
            app.KnudsencurvesCheckBox.Enable = 'off';
            app.KnudsencurvesCheckBox.Text = 'Knudsen curves';
            app.fromSSSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.fromSSSpinner.Limits = [0 6];
            app.fromSSSpinner.Enable = 'off';
            app.toSSSpinner = uispinner(app.UnderwaterNoiseProcessingUIFigure);
            app.toSSSpinner.Limits = [0 6];
            app.toSSSpinner.Enable = 'off';
            app.toSSSpinner.Value = 3;
            app.AutoXCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.AutoXCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoXCheckBoxValueChanged, true);
            app.AutoXCheckBox.Text = 'Auto X';
            app.XminHzEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.XminHzEditField.Limits = [0 Inf];
            app.XminHzEditField.ValueDisplayFormat = '%g';
            app.XminHzEditField.Value = 10;
            app.XmaxHzEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.XmaxHzEditField.Limits = [0 Inf];
            app.XmaxHzEditField.ValueDisplayFormat = '%g';
            app.XmaxHzEditField.Value = 100000;
            app.AutoYCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.AutoYCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoYCheckBoxValueChanged, true);
            app.AutoYCheckBox.Text = 'Auto Y';
            app.YmindBEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.YmindBEditField.ValueDisplayFormat = '%g';
            app.YmindBEditField.Value = 20;
            app.YmaxdBEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.YmaxdBEditField.ValueDisplayFormat = '%g';
            app.YmaxdBEditField.Value = 120;
            app.FigurewidthpixEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.FigurewidthpixEditField.Limits = [400 4000];
            app.FigurewidthpixEditField.Value = 800;
            app.FigureheightpixEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.FigureheightpixEditField.Limits = [300 3000];
            app.FigureheightpixEditField.Value = 600;
            app.PauseafterplotsEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'numeric');
            app.PauseafterplotsEditField.Limits = [0 10];
            app.PauseafterplotsEditField.Value = 0.5;
            app.LogDropDown = uidropdown(app.UnderwaterNoiseProcessingUIFigure);
            app.LogDropDown.Items = {'Standard', 'Detailed', 'Debug'};
            app.LogDropDown.ItemsData = {'0', '1', '2'};
            app.LogDropDown.Value = '0';
            app.TofileCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.TofileCheckBox.ValueChangedFcn = createCallbackFcn(app, @TofileCheckBoxValueChanged, true);
            app.TofileCheckBox.Text = 'To file';
            app.AddsetupCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.AddsetupCheckBox.Enable = 'off';
            app.AddsetupCheckBox.Text = 'Add setup';
            app.RunButton = uibutton(app.UnderwaterNoiseProcessingUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunningLamp = uilamp(app.UnderwaterNoiseProcessingUIFigure);
            app.DoneLamp = uilamp(app.UnderwaterNoiseProcessingUIFigure);
            app.ErrorLamp = uilamp(app.UnderwaterNoiseProcessingUIFigure);
            app.ExitstatusTextArea = uitextarea(app.UnderwaterNoiseProcessingUIFigure);
            app.ExitstatusTextArea.Editable = 'off';
            app.InputfolderEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'text');
            app.InputfolderEditField.Editable = 'off';
            app.CalibrationfileEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'text');
            app.CalibrationfileEditField.Editable = 'off';
            app.OutputfolderEditField = uieditfield(app.UnderwaterNoiseProcessingUIFigure, 'text');
            app.OutputfolderEditField.Editable = 'off';
            app.v506Label = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.v506Label.Text = 'v. 5.06';
            app.infendoffileLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.infendoffileLabel.Text = 'inf = end of file';
            app.nofilterLabel = uilabel(app.UnderwaterNoiseProcessingUIFigure);
            app.nofilterLabel.Text = '0 = no filter';
            app.KeepsizepositionCheckBox = uicheckbox(app.UnderwaterNoiseProcessingUIFigure);
            app.KeepsizepositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepsizepositionCheckBoxValueChanged, true);
            app.KeepsizepositionCheckBox.Text = {'Keep size'; '& position'};
            % Show the figure after all components are created
            app.UnderwaterNoiseProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWNoiPro506

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UnderwaterNoiseProcessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnderwaterNoiseProcessingUIFigure)
        end
    end
end